classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StateButton                matlab.ui.control.Button
        LoadImageButton            matlab.ui.control.Button
        ClearAllButton             matlab.ui.control.Button
        DetectPlatesButton         matlab.ui.control.Button
        ProcessAllButton           matlab.ui.control.Button
        RegonizedTextButton        matlab.ui.control.Button
        SegmentedCharsButton       matlab.ui.control.Button
        DetectionResultsPanel      matlab.ui.container.Panel
        ImagePanel_2               matlab.ui.container.Panel
        RecognizedTextnumberPanel  matlab.ui.container.Panel
        PlateLabel                 matlab.ui.control.Label
        SegmentedCharactersPanel   matlab.ui.container.Panel
        ImagePanel_3               matlab.ui.container.Panel
        CurrentPlatePanel          matlab.ui.container.Panel
        ImageLoadedPanel           matlab.ui.container.Panel
        ImagePanel                 matlab.ui.container.Panel
        Panel                      matlab.ui.container.Panel
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
    end

    
    properties (Access = private)
        originalImage      % Stores the loaded image
        detectedPlates     % Stores detection results
        currentPlateIdx    % Current plate index
        recognizedText     % Recognized text from plate
        originalImagePath   % Path of the loaded image
        SegmentedChars % Description
        BinaryPlate % Description
        PlateFolder % Description
    end
    
    methods (Access = private)


        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            
        [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'}, 'Select an Image');
        if isequal(file,0)
            return; % User canceled
        end
    
        % Store the full file path
        app.originalImagePath = fullfile(path, file);
    
        % Read and store the image matrix for display
        app.originalImage = imread(app.originalImagePath);
    
        % --- Display in ImagePanel ---
        delete(findall(app.ImagePanel,'Type','axes'));
        ax = uiaxes(app.ImagePanel);
        ax.Position = [1 1 app.ImagePanel.Position(3) app.ImagePanel.Position(4)];
        imshow(app.originalImage,'Parent',ax);


        end

        % Button pushed function: DetectPlatesButton
        function DetectPlatesButtonPushed(app, event)
 
        % Check if an image has been loaded
    if isempty(app.originalImagePath)
        uialert(app.UIFigure, 'Please load an image first.', 'No Image');
        return;
    end

    try
        % Read the image
        img = imread(app.originalImagePath);

        % Convert to grayscale if needed
        if size(img,3) == 3
            grayImg = rgb2gray(img);
        else
            grayImg = img;
        end

        %% Preprocessing
        processedImg = imgaussfilt(grayImg,1);
        processedImg = adapthisteq(processedImg);
        processedImg = medfilt2(processedImg,[3 3]);

        %% Edge detection
        edges = edge(processedImg,'sobel');
        edges = bwareaopen(edges,50);

        %% Morphological processing
        se_rect = strel('rectangle',[3,15]);
        se_close = strel('rectangle',[5,5]);
        morphImg = imdilate(edges,se_rect);
        morphImg = imclose(morphImg,se_close);
        morphImg = imfill(morphImg,'holes');
        morphImg = bwareaopen(morphImg,500);

        %% Find candidate plates
        % Get connected components
        cc = bwconncomp(morphImg);
        stats = regionprops(cc, 'BoundingBox', 'Area', 'Extent', 'Solidity');
        
        % Initialize candidates as empty struct array with proper fields
        candidates = struct('BoundingBox', {}, 'Area', {}, 'Extent', {}, 'Solidity', {});
        candidateIdx = 1;
        
        fprintf('Analyzing %d connected components...\n', length(stats));
        
        for i = 1:length(stats)
            bbox = stats(i).BoundingBox;
            width = bbox(3);
            height = bbox(4);
            area = stats(i).Area;
            extent = stats(i).Extent;
            solidity = stats(i).Solidity;
            
            % Malaysian license plate aspect ratio consideration
            % Standard plates: ~3:1, Some plates can be wider: ~4:1 to 5:1
            aspectRatio = width / height;
            
            % More lenient criteria for Malaysian plates
            if (aspectRatio >= 1.8 && aspectRatio <= 7.0) && ...  % Wider range for Malaysian plates
               (area >= 800 && area <= 60000) && ...              % Slightly larger area range
               (extent >= 0.25) && ...                            % More lenient extent
               (solidity >= 0.25) && ...                          % More lenient solidity
               (width >= 60 && height >= 15)                      % Smaller minimum size
                
                % Properly assign struct to struct array
                candidates(candidateIdx).BoundingBox = stats(i).BoundingBox;
                candidates(candidateIdx).Area = stats(i).Area;
                candidates(candidateIdx).Extent = stats(i).Extent;
                candidates(candidateIdx).Solidity = stats(i).Solidity;
                
                fprintf('  Candidate %d: AR=%.2f, Area=%.0f, W=%.0f, H=%.0f\n', ...
                    candidateIdx, aspectRatio, area, width, height);
                
                candidateIdx = candidateIdx + 1;
            end
        end
        
        if isempty(candidates)
            fprintf('No suitable candidates found\n');
        else
            fprintf('Found %d candidates\n', length(candidates));
        end

        %% Validate candidates
        validPlates = struct('BoundingBox', {}, 'Area', {}, 'Extent', {}, 'Solidity', {});
        vIdx = 1;
        for i = 1:length(candidates)
            plateRegion = imcrop(grayImg, candidates(i).BoundingBox);
            % Simple validation using number of objects (characters)
            level = graythresh(plateRegion);
            plateBW1 = imbinarize(plateRegion,level);
            plateBW2 = ~plateBW1;
            methods = {plateBW1, plateBW2};
            bestMethod = 1; bestCount = 0;
            for m = 1:2
                cc2 = bwconncomp(methods{m});
                if cc2.NumObjects >=3 && cc2.NumObjects <=15 && cc2.NumObjects > bestCount
                    bestCount = cc2.NumObjects;
                    bestMethod = m;
                end
            end
            cc2 = bwconncomp(methods{bestMethod});
            if cc2.NumObjects >=2 && cc2.NumObjects <= 15
                validPlates(vIdx) = candidates(i);
                vIdx = vIdx + 1;
            end
        end

        %% Display first detected plate in app panel
        if isempty(validPlates)
            uialert(app.UIFigure, 'No plates detected.', 'Detection Result');
            return;
        end

        plateRegion = imcrop(img, validPlates(1).BoundingBox);
        delete(findall(app.ImagePanel_2,'Type','axes')); % clear previous
        ax = uiaxes(app.ImagePanel_2);
        ax.Position = [1 1 app.ImagePanel_2.Position(3) app.ImagePanel_2.Position(4)];
        imshow(plateRegion,'Parent',ax);

        % Optional: save cropped plate
        imwrite(plateRegion,'DetectedPlate.png');

    catch ME
        uialert(app.UIFigure, ['Error: ' ME.message],'Detection Error');
    end

        
        end

        % Button pushed function: SegmentedCharsButton
        function SegmentedCharsButtonPushed(app, event)

    % File path to the detected plate
    image_path = 'DetectedPlate.png';

    % Load the original color image
    if ~exist(image_path, 'file')
        uialert(app.UIFigure,'Plate image not found!','Error');
        return;
    end
    img = imread(image_path);

    % Convert to grayscale for processing
    if size(img,3)==3
        grayImg = rgb2gray(img);
    else
        grayImg = img;
    end

    % --- Gentle preprocessing ---
    filteredImg = medfilt2(grayImg, [2 2]);
    enhancedImg = adapthisteq(filteredImg, 'ClipLimit',0.01,'NumTiles',[8 8]);

    % --- Thresholding methods ---
    binary1 = imbinarize(enhancedImg,'adaptive','Sensitivity',0.4,'ForegroundPolarity','bright');
    binary2 = imbinarize(enhancedImg,'adaptive','Sensitivity',0.4,'ForegroundPolarity','dark');
    binary3 = imbinarize(enhancedImg);
    binary4 = ~imbinarize(enhancedImg);

    % --- Choose best binary ---
    candidates = {binary1,binary2,binary3,binary4};
    scores = zeros(1,4);
    [h,w] = size(enhancedImg);
    for i=1:4
        cc = bwconncomp(candidates{i});
        stats_temp = regionprops(cc,'Area','BoundingBox');
        valid_count = 0;
        for j=1:length(stats_temp)
            bbox = stats_temp(j).BoundingBox;
            char_h = bbox(4); char_w = bbox(3);
            if stats_temp(j).Area>50 && stats_temp(j).Area<h*w*0.15 && ...
               char_h>h*0.15 && char_h<h*0.85 && char_w>5 && char_w<w*0.25
                valid_count = valid_count + 1;
            end
        end
        scores(i) = valid_count;
    end
    [~, best_idx] = max(scores);
    binary_plate = candidates{best_idx};

    % --- Minimal morphological repair ---
    cc_before = bwconncomp(binary_plate);
    if cc_before.NumObjects>15
        se_h = strel('rectangle', [1 3]);      % horizontal line to connect fragments
        binary_plate = imclose(binary_plate, se_h);  
        binary_plate = bwareaopen(binary_plate, 15);
    end
    cc_after = bwconncomp(binary_plate);
    if cc_after.NumObjects<4
        se_small = strel('disk',1);
        opened = imopen(binary_plate,se_small);
        cc_opened = bwconncomp(opened);
        if cc_opened.NumObjects>cc_after.NumObjects && cc_opened.NumObjects<12
            binary_plate = opened;
        end
    end

    % --- Detect characters ---
    [labeled_img,num_objects] = bwlabel(binary_plate,8);
    stats = regionprops(labeled_img,'BoundingBox','Area','Extent','Solidity');
    valid_chars = [];
    img_h = size(binary_plate,1); img_w = size(binary_plate,2);
    for i=1:num_objects
        area = stats(i).Area; bbox = stats(i).BoundingBox;
        w = bbox(3); h = bbox(4); ar = w/h; ext=stats(i).Extent; sol=stats(i).Solidity;
        if area>=img_h*img_w*0.002 && area<=img_h*img_w*0.2 && h>=img_h*0.1 && h<=img_h*0.9 && ...
           w>=3 && w<=img_w*0.3 && ar>=0.1 && ar<=3.0 && ext>=0.15 && sol>=0.2
            valid_chars = [valid_chars,i];
        end
    end
    % Sort characters left-to-right
    if ~isempty(valid_chars)
        centers = arrayfun(@(i) stats(i).BoundingBox(1)+stats(i).BoundingBox(3)/2, valid_chars);
        [~,idx] = sort(centers);
        valid_chars = valid_chars(idx);
    end

    % --- Display final color plate in panel ---
    delete(findall(app.ImagePanel_3,'Type','axes')); % Clear previous
    ax = uiaxes(app.ImagePanel_3);
    ax.Position = [1 1 app.ImagePanel_3.Position(3) app.ImagePanel_3.Position(4)];
    imshow(img,'Parent',ax);
    hold(ax,'on');
    for i=1:length(valid_chars)
        bbox = stats(valid_chars(i)).BoundingBox;
        rectangle(ax,'Position',bbox,'EdgeColor','g','LineWidth',2);
    end
    hold(ax,'off');

    % --- Create output folder ---
    [pathstr,name,~] = fileparts(image_path);
    if isempty(pathstr), pathstr = pwd; end
    output_dir = fullfile(pathstr, [name '_segmented_characters']);
    if ~exist(output_dir,'dir'), mkdir(output_dir); end

    % --- Save binary plate ---
    imwrite(binary_plate, fullfile(output_dir, 'binary_plate.png'));

    % --- Extract and save each character ---
    segmented_chars = {};
    for i = 1:length(valid_chars)
        bbox = stats(valid_chars(i)).BoundingBox;
        x = round(bbox(1)); y = round(bbox(2));
        width = round(bbox(3)); height = round(bbox(4));
        padding = 2;
        x1 = max(1, x-padding); y1 = max(1, y-padding);
        x2 = min(size(enhancedImg,2), x+width+padding);
        y2 = min(size(enhancedImg,1), y+height+padding);
        char_img = enhancedImg(y1:y2, x1:x2);
        char_img_resized = imresize(char_img, [64,48], 'bilinear');
        segmented_chars{i} = char_img_resized;

        % Save each character
        filename = fullfile(output_dir, sprintf('char_%02d.png', i));
        imwrite(char_img_resized, filename);
    end

    % --- Store in app properties for later use ---
    app.SegmentedChars = segmented_chars;
    app.BinaryPlate = binary_plate;
    app.PlateFolder = output_dir;

    uialert(app.UIFigure, sprintf('Segmentation completed!\nSaved in:\n%s', output_dir), 'Success');


        end

        % Button pushed function: RegonizedTextButton
        function RegonizedTextButtonPushed(app, event)
        % Folder path containing segmented character images
        folder_path = app.PlateFolder;  % This should have been set in SegmentedCharsButtonPushed
        
        if ~exist(folder_path, 'dir')
            uialert(app.UIFigure, 'Segmented character folder not found. Please run segmentation first.', 'Error');
            return;
        end
    
        % Get list of character images
        charFiles = dir(fullfile(folder_path, 'char*.png'));
        if isempty(charFiles)
            uialert(app.UIFigure, 'No character images found in the folder.', 'Error');
            return;
        end
        
        recognizedText = cell(length(charFiles), 1); % Store each character text
        
        for i = 1:length(charFiles)
            img = imread(fullfile(folder_path, charFiles(i).name));
            
            % Optional: display character image for debugging
            % figure; imshow(img); title(sprintf('Character %d: %s', i, charFiles(i).name));
            
            % Perform OCR
            ocrResults = ocr(img, LayoutAnalysis="block");
            recognizedText{i} = ocrResults.Text;
            
            % Print individual character result to Command Window
            fprintf('Character %d (%s): "%s"\n', i, charFiles(i).name, strtrim(recognizedText{i}));
        end
        
        % Combine all recognized characters
        fullText = strjoin(cellfun(@strtrim, recognizedText, 'UniformOutput', false), '');
        
        % Display in the Label
        app.PlateLabel.Text = fullText;            
        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
    % --- Step 1: Load original image ---
    if isempty(app.originalImagePath)
        uialert(app.UIFigure, 'Please load an image first.', 'No Image');
        return;
    end
    img = imread(app.originalImagePath);
    if size(img,3) == 3
        grayImg = rgb2gray(img);
    else
        grayImg = img;
    end

    % --- Step 2: Detect plates (same as DetectPlatesButtonPushed) ---
    try
        processedImg = imgaussfilt(grayImg,1);
        processedImg = adapthisteq(processedImg);
        processedImg = medfilt2(processedImg,[3 3]);

        edges = edge(processedImg,'sobel');
        edges = bwareaopen(edges,50);

        se_rect = strel('rectangle',[3,15]);
        se_close = strel('rectangle',[5,5]);
        morphImg = imdilate(edges,se_rect);
        morphImg = imclose(morphImg,se_close);
        morphImg = imfill(morphImg,'holes');
        morphImg = bwareaopen(morphImg,500);

        cc = bwconncomp(morphImg);
        stats = regionprops(cc,'BoundingBox','Area','Extent','Solidity');

        % Validate candidates
        candidates = struct('BoundingBox', {}, 'Area', {}, 'Extent', {}, 'Solidity', {});;
        idx = 1;
        for i = 1:length(stats)
            bbox = stats(i).BoundingBox;
            w = bbox(3); h = bbox(4);
            ar = w/h;
            if (ar >= 1.8 && ar <= 7.0) && ...
               (stats(i).Area >= 800 && stats(i).Area <= 60000) && ...
               (stats(i).Extent >= 0.25) && (stats(i).Solidity >= 0.25) && ...
               (w >= 60 && h >= 15)
                candidates(idx) = stats(i); %#ok<AGROW>
                idx = idx + 1;
            end
        end

        if isempty(candidates)
            uialert(app.UIFigure,'No plates detected.','Detection Result');
            return;
        end

        % Crop first detected plate
        plateRegion = imcrop(img, candidates(1).BoundingBox);

        % Show detected plate in ImagePanel_2
        delete(findall(app.ImagePanel_2,'Type','axes'));
        ax2 = uiaxes(app.ImagePanel_2);
        ax2.Position = [1 1 app.ImagePanel_2.Position(3) app.ImagePanel_2.Position(4)];
        imshow(plateRegion,'Parent',ax2);

        % Save for segmentation
        detectedPlatePath = fullfile(pwd,'DetectedPlate.png');
        imwrite(plateRegion, detectedPlatePath);

    catch ME
        uialert(app.UIFigure, ['Error detecting plate: ' ME.message], 'Error');
        return;
    end

    % --- Step 3: Segmentation (same as SegmentedCharsButtonPushed) ---
    grayPlate = rgb2gray(plateRegion);

    filteredImg = medfilt2(grayPlate, [2 2]);
    enhancedImg = adapthisteq(filteredImg, 'ClipLimit',0.01,'NumTiles',[8 8]);

    binary1 = imbinarize(enhancedImg,'adaptive','Sensitivity',0.4,'ForegroundPolarity','bright');
    binary2 = imbinarize(enhancedImg,'adaptive','Sensitivity',0.4,'ForegroundPolarity','dark');
    binary3 = imbinarize(enhancedImg);
    binary4 = ~imbinarize(enhancedImg);

    candidates_bin = {binary1,binary2,binary3,binary4};
    scores = zeros(1,4);
    [h,w] = size(enhancedImg);
    for i=1:4
        cc = bwconncomp(candidates_bin{i});
        stats_temp = regionprops(cc,'Area','BoundingBox');
        valid_count = 0;
        for j=1:length(stats_temp)
            bbox = stats_temp(j).BoundingBox;
            char_h = bbox(4); char_w = bbox(3);
            if stats_temp(j).Area>50 && stats_temp(j).Area<h*w*0.15 && ...
               char_h>h*0.15 && char_h<h*0.85 && char_w>5 && char_w<w*0.25
                valid_count = valid_count + 1;
            end
        end
        scores(i) = valid_count;
    end
    [~, best_idx] = max(scores);
    binary_plate = candidates_bin{best_idx};

    % Morphological repair
    cc_before = bwconncomp(binary_plate);
    if cc_before.NumObjects>15
        se_h = strel('rectangle', [1 3]);
        binary_plate = imclose(binary_plate, se_h);  
        binary_plate = bwareaopen(binary_plate, 15);
    end
    cc_after = bwconncomp(binary_plate);
    if cc_after.NumObjects<4
        se_small = strel('disk',1);
        opened = imopen(binary_plate,se_small);
        cc_opened = bwconncomp(opened);
        if cc_opened.NumObjects>cc_after.NumObjects && cc_opened.NumObjects<12
            binary_plate = opened;
        end
    end

    [labeled_img,num_objects] = bwlabel(binary_plate,8);
    stats_chars = regionprops(labeled_img,'BoundingBox','Area','Extent','Solidity');
    valid_chars = [];
    img_h = size(binary_plate,1); img_w = size(binary_plate,2);
    for i=1:num_objects
        area = stats_chars(i).Area; bbox = stats_chars(i).BoundingBox;
        w = bbox(3); h = bbox(4); ar = w/h; ext=stats_chars(i).Extent; sol=stats_chars(i).Solidity;
        if area>=img_h*img_w*0.002 && area<=img_h*img_w*0.2 && h>=img_h*0.1 && h<=img_h*0.9 && ...
           w>=3 && w<=img_w*0.3 && ar>=0.1 && ar<=3.0 && ext>=0.15 && sol>=0.2
            valid_chars = [valid_chars,i]; %#ok<AGROW>
        end
    end
    if ~isempty(valid_chars)
        centers = arrayfun(@(i) stats_chars(i).BoundingBox(1)+stats_chars(i).BoundingBox(3)/2, valid_chars);
        [~,idx] = sort(centers);
        valid_chars = valid_chars(idx);
    end

    % Create folder for characters
    [pathstr,name,~] = fileparts(detectedPlatePath);
    output_dir = fullfile(pathstr, [name '_segmented_characters']);
    if ~exist(output_dir,'dir'), mkdir(output_dir); end

    imwrite(binary_plate, fullfile(output_dir, 'binary_plate.png'));

    segmented_chars = {};
    for i = 1:length(valid_chars)
        bbox = stats_chars(valid_chars(i)).BoundingBox;
        x = round(bbox(1)); y = round(bbox(2));
        width = round(bbox(3)); height = round(bbox(4));
        padding = 2;
        x1 = max(1, x-padding); y1 = max(1, y-padding);
        x2 = min(size(enhancedImg,2), x+width+padding);
        y2 = min(size(enhancedImg,1), y+height+padding);
        char_img = enhancedImg(y1:y2, x1:x2);
        char_img_resized = imresize(char_img, [64,48], 'bilinear');
        segmented_chars{i} = char_img_resized;

        filename = fullfile(output_dir, sprintf('char_%02d.png', i));
        imwrite(char_img_resized, filename);
    end

    app.PlateFolder = output_dir;
    app.SegmentedChars = segmented_chars;
    app.BinaryPlate = binary_plate;

    % Show segmented plate in ImagePanel_3
    delete(findall(app.ImagePanel_3,'Type','axes'));
    ax3 = uiaxes(app.ImagePanel_3);
    ax3.Position = [1 1 app.ImagePanel_3.Position(3) app.ImagePanel_3.Position(4)];
    imshow(plateRegion,'Parent',ax3);
    hold(ax3,'on');
    for i=1:length(valid_chars)
        bbox = stats_chars(valid_chars(i)).BoundingBox;
        rectangle(ax3,'Position',bbox,'EdgeColor','g','LineWidth',2);
    end
    hold(ax3,'off');

    % --- Step 4: OCR ---
    charFiles = dir(fullfile(output_dir, 'char*.png'));
    fullText = '';
    for i=1:length(charFiles)
        img_char = imread(fullfile(output_dir, charFiles(i).name));
        ocrResults = ocr(img_char,'LayoutAnalysis','block');
        fullText = [fullText strtrim(ocrResults.Text)];
    end

    % Display recognized plate text
    app.PlateLabel.Text = fullText;
    uialert(app.UIFigure,sprintf('Processing completed!\nDetected plate text: %s', fullText),'Success');


        % --- Step 5: Detect state from plate ---
    % Define state mapping (first letter(s) -> state)
    stateMap = containers.Map( ...
        {'A','B','C','D','F','G','J','K','M','N','P','Q','R','S','T','V','W','X','Y','Z'}, ...
        {'Perak','Selangor','Pahang','Kelantan','Putrajaya','Sabah','Johor','Kedah','Malacca','Negeri Sembilan', ...
         'Penang','Perlis','Sarawak','Selangor','Terengganu','Sabah','Federal Territory','Sarawak','Kelantan','Sabah'} ...
    );
    
    % Extract first letter from recognized text
    if ~isempty(fullText)
        firstLetter = upper(fullText(1));
        if isKey(stateMap, firstLetter)
            detectedState = stateMap(firstLetter);
        else
            detectedState = 'Unknown';
        end
    else
        detectedState = 'Unknown';
    end
    
    % Display both plate and state
    app.PlateLabel.Text = sprintf('%s | State: %s', fullText, detectedState);
    uialert(app.UIFigure,sprintf('Processing completed!\nDetected plate: %s\nState: %s', fullText, detectedState),'Success');
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
    % Clear the main image panel
    delete(findall(app.ImagePanel,'Type','axes'));

    % Clear the detected plate panel
    delete(findall(app.ImagePanel_2,'Type','axes'));

    % Clear the segmented characters panel
    delete(findall(app.ImagePanel_3,'Type','axes'));

    % Clear the Plate text label
    app.PlateLabel.Text = '';

    % Reset app properties
    app.originalImage = [];
    app.originalImagePath = '';
    app.detectedPlates = [];
    app.currentPlateIdx = [];
    app.recognizedText = '';
    app.SegmentedChars = {};
    app.BinaryPlate = [];
    app.PlateFolder = '';

    % Optional: alert user
    uialert(app.UIFigure,'All panels and text cleared!','Cleared');            
        end

        % Button pushed function: StateButton
        function StateButtonPushed(app, event)
       % --- Folder containing segmented character images ---
        folder_path = app.PlateFolder;  % Already set in SegmentedCharsButtonPushed
    
        if ~exist(folder_path,'dir')
            uialert(app.UIFigure, 'Segmented character folder not found. Run segmentation first.', 'Error');
            return;
        end
    
        % --- Get all character images ---
        charFiles = dir(fullfile(folder_path,'char*.png'));
        if isempty(charFiles)
            uialert(app.UIFigure,'No character images found in the folder.','Error');
            return;
        end
    
        recognizedText = cell(length(charFiles),1);
    
        % --- OCR on each character ---
        for i = 1:length(charFiles)
            img = imread(fullfile(folder_path,charFiles(i).name));
            ocrResults = ocr(img,'LayoutAnalysis','block');
            recognizedText{i} = strtrim(ocrResults.Text);
        end
    
        % --- Combine all recognized characters ---
        fullPlate = strjoin(recognizedText,'');
        fullPlate = upper(strrep(fullPlate,' ',''));  % Remove spaces & uppercase
    
        % --- Determine Malaysian state by first two letters ---
        stateCode = '';
        if length(fullPlate)>=2
            stateCode = fullPlate(1:2);
        end
    
        % Mapping of Malaysian states by plate prefix
        stateMap = containers.Map( ...
            {'A','B','C','D','E','F','J','K','M','N','P','Q','R','S','T','V','W','X','Y','Z'}, ...
            {'Perak','Selangor','Pahang','Kelantan','Terengganu','Penang','Johor','Sabah','Malacca','Negeri Sembilan','Penang','Sarawak','Sabah','Sarawak','Terengganu','Labuan','Kuala Lumpur','Sarawak','Labuan','Sabah'} ...
        );
    
        stateName = 'Unknown';
        if isKey(stateMap,stateCode(1))
            stateName = stateMap(stateCode(1));
        end
    
        % --- Display the results in app ---
        app.PlateLabel.Text = sprintf('Plate: %s | State: %s', fullPlate, stateName);
    
        % Optional: print to command window
        fprintf('Detected Plate: %s\n', fullPlate);
        fprintf('State: %s\n', stateName);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.ImageLoadedPanel = uipanel(app.UIFigure);
            app.ImageLoadedPanel.Title = 'Image Loaded';
            app.ImagePanel = uipanel(app.ImageLoadedPanel);
            app.DetectionResultsPanel = uipanel(app.UIFigure);
            app.DetectionResultsPanel.Title = 'Detection Results';
            app.CurrentPlatePanel = uipanel(app.DetectionResultsPanel);
            app.CurrentPlatePanel.Title = 'Current Plate';
            app.SegmentedCharactersPanel = uipanel(app.DetectionResultsPanel);
            app.SegmentedCharactersPanel.Title = 'Segmented Characters';
            app.ImagePanel_3 = uipanel(app.SegmentedCharactersPanel);
            app.RecognizedTextnumberPanel = uipanel(app.DetectionResultsPanel);
            app.RecognizedTextnumberPanel.Title = 'Recognized Text/number';
            app.PlateLabel = uilabel(app.RecognizedTextnumberPanel);
            app.ImagePanel_2 = uipanel(app.DetectionResultsPanel);
            app.SegmentedCharsButton = uibutton(app.UIFigure, 'push');
            app.SegmentedCharsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentedCharsButtonPushed, true);
            app.SegmentedCharsButton.Text = 'Segmented Chars';
            app.RegonizedTextButton = uibutton(app.UIFigure, 'push');
            app.RegonizedTextButton.ButtonPushedFcn = createCallbackFcn(app, @RegonizedTextButtonPushed, true);
            app.RegonizedTextButton.Text = 'Regonized Text';
            app.ProcessAllButton = uibutton(app.UIFigure, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Process All';
            app.DetectPlatesButton = uibutton(app.UIFigure, 'push');
            app.DetectPlatesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectPlatesButtonPushed, true);
            app.DetectPlatesButton.Text = 'Detect Plates';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.StateButton = uibutton(app.UIFigure, 'push');
            app.StateButton.ButtonPushedFcn = createCallbackFcn(app, @StateButtonPushed, true);
            app.StateButton.Text = 'State';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.Tag = 'StatusLabel';
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end